classdef collect_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
        btnFlag = 0; % 是否按下按钮
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % 窗口最大化
%             drawnow
%             app.UIFigure.WindowState = 'maximized';
            app.CallingApp = mainapp;
            % 主界面按钮不可按
            app.CallingApp.Button_sim.Enable='off';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.btnFlag = not(app.btnFlag);
            if app.btnFlag==1
                % 按下按钮主界面recordFlag为1，同时按钮变为不可按
%                 Record(app.CallingApp) % 不采集！
                app.Button.Enable = 'off';
                % 初始化空白界面
                axis(app.UIAxes, 'image');im = imread('.\pictures\blank.jpg');
                imagesc(app.UIAxes,im);pause(1);
                % 实验开始
                axis(app.UIAxes, 'image');im = imread('.\pictures\start.jpg');
                imagesc(app.UIAxes,im);pause(3);
                % 请放松
                axis(app.UIAxes, 'image');im = imread('.\pictures\resting_state_10s_1.jpg');
                imagesc(app.UIAxes,im);pause(2);
                % 请放松保持10秒
                axis(app.UIAxes, 'image');im = imread('.\pictures\resting_state_10s_2.jpg');
                imagesc(app.UIAxes,im);
                GetTrigger(app.CallingApp, 5); % 发送标签5
                pause(13);
                GetTrigger(app.CallingApp, 0); % 发送标签0恢复默认
                % 顺序采集
                movs = 4;
                for i = 1:movs
                    % 准备开始实验
                    axis(app.UIAxes, 'image');im = imread(['.\pictures\ready_' num2str(i) '.jpg']);
                    imagesc(app.UIAxes,im);pause(3);
                    axis(app.UIAxes, 'image');im = imread('.\pictures\rest.jpg');
                    imagesc(app.UIAxes,im);pause(3);
                    for j = 1:3
                        axis(app.UIAxes, 'image');im = imread(['.\pictures\go_' num2str(i) '.jpg']);
                        imagesc(app.UIAxes,im);
                        GetTrigger(app.CallingApp, i); % 发送标签1-4
                        pause(2);
                        GetTrigger(app.CallingApp, 0); % 发送标签0恢复默认
                        axis(app.UIAxes, 'image');im = imread('.\pictures\rest.jpg');
                        imagesc(app.UIAxes,im);pause(2+rand(1));
                    end
                    if i < movs
                        axis(app.UIAxes, 'image');im = imread('.\pictures\break_10s.jpg');
                        imagesc(app.UIAxes,im);pause(3);
                    end
                end
                % 实验结束
                axis(app.UIAxes, 'image');im = imread('.\pictures\sim_over.jpg');
                imagesc(app.UIAxes,im);
                % 实验结束后recordFlag置为0，同时按钮文字变为返回主界面且按钮可按
%                 Record(app.CallingApp) % 不采集！！！
                app.Button.Text = '返回主界面';
                app.Button.Enable = 'on';
            else
                % 按下结束按钮主界面按钮恢复可按，关闭当前界面，返回主界面，
                app.CallingApp.Button_sim.Enable='on';
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 主界面按钮恢复可按再关闭app
            app.CallingApp.Button_sim.Enable='on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '模拟采集';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = collect_sim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end