classdef signals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button_onlinetest  matlab.ui.control.Button
        Button_localtest   matlab.ui.control.Button
        Button_train       matlab.ui.control.Button
        Button_sim         matlab.ui.control.Button
        Button_rand        matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        Label_2            matlab.ui.control.Label
        btnRecord          matlab.ui.control.Button
        popAmp             matlab.ui.control.DropDown
        Label              matlab.ui.control.Label
        btnConnect         matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n = 250; % 一次读入的点数，1个点33个字节
        step = 250; % 坐标轴补偿，应该和读入字节数相同
        x = 0;
        num = 0;
        btnConnectFlag = 0; % 是否按下连接按钮
        recordFlag = 0; % 是否录制数据
        ch1=0;ch2=0;ch3=0;ch4=0;ch5=0;ch6=0;ch7=0;ch8=0;ch9=0;battery=0;
        sp % 各个通道间隔
        amp % 控件的显示幅值
        tcp % 串口
        plotHandle % 绘图
        txtax % 是否录制数据的文字提示
        fileTime % 时间
        recordPath % 数据记录地址
        trigger = 0 % 标签（默认为0）
        Paradigm % 范式界面
        chan_num; % 通道数目
        state = 0; % 状态 0：刚采集到数据；1：保存数据
        move_data; % 记录运动状态数据
        obj_;
        test_text = ['放松', '一', '二', '三', '四', '五', '六', '七', '八', '九', '十']
    end
    
    properties (Access = public)
        test_flag = 0; % 是否开始测试
        y_pred = 0;
        y_pred_final = 0;
    end
    
    methods (Access = private)
        %% =================IP自动获取====================================
        function lip=ip(app)
            %功能：获取电脑IP地址。
            [status,result]=system('ipconfig');
            try
                r=regexp(result,'IPv4 地址 . . . . . . . . . . . . : \d{1,3}.\d{1,3}.\d{1,3}.\d{1,3}','match');
                r=r{1};
                r=regexp(r,'\d{1,3}.\d{1,3}.\d{1,3}.\d{1,3}','match');
                lip=r{1};
            catch %如果在没有联网的情况下
                lip='请检查网络';
            end
        end
        %% ==============数据解析并且画图=================================
        function DataParser(app, src, event, bytesToRead)
%             disp([datestr(now,'HHMMSS'), '_', num2str(app.trigger)])
            data_recv = fread(app.tcp, bytesToRead);
            data_recv1 = reshape(data_recv,[33,bytesToRead/33]);
            data_recv2 = data_recv1;
            road = data_recv1([3:3:30],:);
            data_recv2([3+0:3:30+0],:) = data_recv2([3:3:30],:)*2^16;
            data_recv2([3+1:3:30+1],:) = data_recv2([3+1:3:30+1],:)*2^8;
            data_recv2([3+2:3:30+2],:) = data_recv2([3+2:3:30+2],:)*2^0;
            % 通道
            data_channel = ones(10,bytesToRead/33);
            for i = 1:10
                data_channel(i,:) = sum(data_recv2(3*i:3*i+2,:),1);
            end
            %查找大于等于2^7的
            idx_chn = find(road>=2^7);
            %大于等于2^7减去2^24
            data_channel(idx_chn) = data_channel(idx_chn)-2^24;
            data_channel(9,:) = app.trigger; % 打标签
            app.ch1 = [app.ch1 data_channel(1,:)];app.ch2 = [app.ch2 data_channel(2,:)];
            app.ch3 = [app.ch3 data_channel(3,:)];app.ch4 = [app.ch4 data_channel(4,:)];
            app.ch5 = [app.ch5 data_channel(5,:)];app.ch6 = [app.ch6 data_channel(6,:)];
            app.ch7 = [app.ch7 data_channel(7,:)];app.ch8 = [app.ch8 data_channel(8,:)];
            app.ch9 = [app.ch9 data_channel(9,:)]; % 标签信号
            app.battery=data_channel(10,:);
            
            % app.ch1=0;app.ch3=0;app.ch4=0;app.ch5=0;app.ch6=0;app.ch7=0;app.ch8=0;

            % 电量显示
            if app.battery(1,1) > 100
                app.TextArea.Value = [num2str(app.battery(1,1)-1),'%'];
            else
                app.TextArea.Value = [num2str(app.battery(1,1)),'%'];
            end
            app.TextArea.BackgroundColor = [1-app.battery(1,1)/101,app.battery(1,1)/101,0];
            
            adc = 0.022351744; % ADC分辨率（需乘以这个值才是原始数据）
            adc_diff = 0.;
            Data_=[data_channel(1:8,:)*0.022351744;data_channel(9,:)]; % ;data_channel(10,:)-1
            % 按下开始录制键后实时记录数据
            if app.recordFlag==1
                writematrix(Data_',app.recordPath,'WriteMode','append')
            end

            app.x = app.x + app.step;
            app.num = app.num + 1;
            if  app.x<5000 && app.num>=1
                set(app.plotHandle(1),'XData',1:length(app.ch1(1:end)) ,'YData',-app.ch1(1:end)*adc*app.amp+app.sp); % 实际值：app.ch1(1:end)*0.022351744
                set(app.plotHandle(2),'XData',1:length(app.ch2(1:end)), 'YData',-app.ch2(1:end)*adc*app.amp+2*app.sp);
                set(app.plotHandle(3),'XData',1:length(app.ch3(1:end)), 'YData',-app.ch3(1:end)*adc*app.amp+3*app.sp);
                set(app.plotHandle(4),'XData',1:length(app.ch4(1:end)), 'YData',-app.ch4(1:end)*adc*app.amp+4*app.sp);
                set(app.plotHandle(5),'XData',1:length(app.ch5(1:end)) ,'YData',-app.ch5(1:end)*adc*app.amp+5*app.sp);
                set(app.plotHandle(6),'XData',1:length(app.ch6(1:end)), 'YData',-app.ch6(1:end)*adc*app.amp+6*app.sp);
                set(app.plotHandle(7),'XData',1:length(app.ch7(1:end)), 'YData',-app.ch7(1:end)*adc*app.amp+7*app.sp);
                set(app.plotHandle(8),'XData',1:length(app.ch8(1:end)), 'YData',-app.ch8(1:end)*adc*app.amp+8*app.sp);
                set(app.plotHandle(9),'XData',1:length(app.ch9(1:end)), 'YData',app.ch9(1:end)*0.1*app.sp+9*app.sp,'color',[0,0,0]);
                set(app.plotHandle(10),'XData',[length(app.ch9(1:end)),length(app.ch9(1:end))], 'YData',[0,10*app.sp],'LineWidth',2,'color',[1,0.5,0.5],'LineStyle','-');

                drawnow
            else
                app.x=0;
                app.ch1=[];app.ch2=[];
                app.ch3=[];app.ch4=[];
                app.ch5=[];app.ch6=[];
                app.ch7=[];app.ch8=[];
                app.ch9=[];
                drawnow
            end

            if app.test_flag == 1
%                 disp('进入测试状态')
                delete(app.txtax);
                aa = app.obj_.online_test(Data_, app.state, app.move_data);
                app.state = int64(aa{1});
                app.move_data = double(aa{2});
                app.y_pred = int64(aa{3});
                if app.y_pred ~= 0
                    app.y_pred_final = app.y_pred;
                    app.test_flag = 0;
                end
            end

        end

    end
    
    
    methods (Access = public)
        
        function GetTrigger(app, trigger)
            app.trigger = trigger;
        end
        
        function Record(app)
            app.fileTime = datestr(now,'yyyymmddTHHMMSS');
            app.recordFlag = not(app.recordFlag);
            if app.recordFlag==1
%                 app.data=[];    % 开始记录时清空data
                app.btnRecord.Text='暂停记录';
                delete(app.txtax);
                app.txtax=text(app.UIAxes,2000,5*app.sp,'Recording','fontsize',30,'color',[1,0.8,0.8],'fontname','times new Roman');
                app.recordPath = [pwd,'\data\',app.fileTime,'.txt'];
            else
                app.btnRecord.Text='开始记录';
                delete(app.txtax);
                app.txtax=text(app.UIAxes,1800,5*app.sp,'Data Not Recording','fontsize',30,'color',[0.5,0.8,0.8],'fontname','times new Roman');
                % Data=[data(1:8,:)*0.022351744;data(9,:);data(10,:)-1];  % ADC分辨率
%                 Data=[app.data(1:8,:)*0.022351744;app.data(9,:)];
%                 save([pwd,'\data\',app.fileTime],'Data')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% =========创建保存数据文件夹==========================
            if ~exist([pwd,'\data'],'file')
                mkdir([pwd,'\data\']);
            end
            
            if ~exist([pwd,'\log'],'file')
                mkdir([pwd,'\log\']);
            end
            
            logname = ['log-' datestr(now,'yyyymmddTHHMMSS');];
            diary([pwd,'\log\',logname]);
            
            %% =========坐标轴初始化==========================
            value = app.popAmp.Value;
            app.sp = str2double(strtok(value,'μ'));
            app.amp = app.sp/str2double(strtok(value,'μ'));
            app.UIAxes.YTick = [app.sp:app.sp:9*app.sp];
            app.UIAxes.YLim = [0, 10*app.sp];
            app.plotHandle = plot(app.UIAxes,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,'-','LineWidth',0.01,'ButtonDownFcn','off');
            app.txtax = text(app.UIAxes,1800,5*app.sp,'Data Not Recording','fontsize',30,'color',[0.5,0.8,0.8],'fontname','times new Roman');
        end

        % Callback function: UIAxes, btnConnect
        function btnConnectPushed(app, event)
            app.btnConnectFlag = not(app.btnConnectFlag);
            if app.btnConnectFlag == 1
                app.btnRecord.Text='开始记录';
                app.btnRecord.Enable='on';
                app.btnConnect.Text='断开连接';
                conn.Ip = '192.168.31.3';
                conn.RemIp = '192.168.31.60';
                conn.Port = 10002;
                conn.Type = 'server';
%                 app.tcp = tcpserver(conn.Ip, conn.Port); % 创建并开启服务器，服务器地址为conn.Ip=192.168.31.3，端口号为conn.Port=10002
                app.tcp = tcpip(conn.Ip,conn.Port,'NetworkRole',conn.Type);%与第一个请求连接的客户机建立连接，端口号为10008，类型为服务器。
                app.tcp.timeout=1;
                app.tcp.InputBuffersize=33*5000; % 缓存
                app.tcp.RemoteHost=conn.RemIp; % 客户端ip
                try
                    webread(['http://',conn.RemIp,'/cgi-bin/save_config?ip=',ip(app),'&ip_port=','10002','&ApiKey=not%20necessary&LocalIP=192.168.31.61&BAUD=1500000&NCHAN=10']);
                    webread(['http://',conn.RemIp,'/cgi-bin/MultiTPDstart']);%http://192.168.31.61/cgi-bin/MultiTPDstart
                catch
                    disp('检查设备是否连接上')
                    return
                end
                bytesToRead = 33*app.n;% 设置一次读取的字节数，设置大一点(如n=100)可以防止java.lang.NullPointerException问题(无法进入回调函数)
                %  定义当输入缓冲区中达到所需字节数时要执行的回调函数  注意：回调函数必须在开启服务之前
                app.tcp.BytesAvailableFcn = {@app.DataParser, bytesToRead}; % 可读字节数回调函数，当可读取字节数超过一定范围或者接收特定的结束符时候才调用
                app.tcp.BytesAvailableFcnMode = 'byte'; % 设置BytesAvailableFcn的函数调用模式
                app.tcp.BytesAvailableFcnCount = bytesToRead; % 调用BytesAvailableFcn的字节数
                
                fopen(app.tcp); % 打开服务器，直到建立一个TCP连接才返回；
                fprintf(app.tcp,'C');%开启服务
                disp('连接');
            else
                app.btnRecord.Text='暂停记录';
                app.btnConnect.Text='连接';
                app.btnRecord.Enable='off';
                try
                    fprintf(app.tcp,'K'); % 关闭服务
                    fclose(app.tcp); % 关闭连接
                    delete(app.tcp); % 删除连接
                    clear app.tcp; % 清除连接
                    % 断开连接
                    disp('断开连接');
                catch
                end
            end
        end

        % Value changed function: popAmp
        function popAmpValueChanged(app, event)
            value = app.popAmp.Value;
            app.amp = app.sp/str2double(strtok(value,'μ'));
        end

        % Button pushed function: btnRecord
        function btnRecordPushed(app, event)
            app.fileTime = datestr(now,'yyyymmddTHHMMSS');
            app.recordFlag = not(app.recordFlag);
            if app.recordFlag==1
                app.btnRecord.Text='暂停记录';
                delete(app.txtax);
                app.txtax=text(app.UIAxes,2000,5*app.sp,'Recording','fontsize',30,'color',[1,0.8,0.8],'fontname','times new Roman');
                app.recordPath = [pwd,'\data\',app.fileTime,'.txt'];
            else
                app.btnRecord.Text='开始记录';
                delete(app.txtax);
                app.txtax=text(app.UIAxes,1800,5*app.sp,'Data Not Recording','fontsize',30,'color',[0.5,0.8,0.8],'fontname','times new Roman');
                % Data=[data(1:8,:)*0.022351744;data(9,:);data(10,:)-1];  % ADC分辨率
%                 Data=[app.data(1:8,:)*0.022351744;app.data(9,:)];
%                 save([pwd,'\data\',app.fileTime],'Data')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 清理接口对象
            try
                fprintf(app.tcp,'K'); % 关闭服务
                fclose(app.tcp); % 关闭连接
                delete(app.tcp); % 删除连接
                clear app.tcp; % 清除连接
                delete(app.Paradigm);delete(app);
                disp('关闭');
            catch
                delete(app.Paradigm);delete(app);
                disp('关闭');
            end
        end

        % Button pushed function: Button_rand
        function Button_randPushed(app, event)
            app.Paradigm = ran_collect(app);
        end

        % Button pushed function: Button_sim
        function Button_simPushed(app, event)
            app.Paradigm = collect_sim(app);
        end

        % Button pushed function: Button_train
        function Button_train_Pushed(app, event)
            app.Button_train.Text = '模型训练中';
            app.Button_train.Enable='off';
            import py.CSP_SVM_train.*
            % 重载Python模块
            app.obj_ = py.importlib.import_module('CSP_SVM_train');
            py.importlib.reload(app.obj_);
%             app.chan_num = 4;
%             app.obj_.training('./data/20221103T095410.txt');
            app.obj_.training(app.recordPath);  % , app.chan_num
            app.Button_train.Text = '模型训练';
            app.Button_train.Enable='on';
        end

        % Button pushed function: Button_localtest
        function Button_localtest_Pushed(app, event)
            import py.CSP_SVM_test.*
            % 重载Python模块
            app.obj_ = py.importlib.import_module('CSP_SVM_test');
            py.importlib.reload(app.obj_);
%             app.chan_num = 4;
%             app.obj_.testing('./data/junhan_2MI_20230717T115414.txt');
            app.obj_.testing(app.recordPath);
        end

        % Button pushed function: Button_onlinetest
        function Button_onlinetest_Pushed(app, event)
            import py.CSP_SVM_test.*
            % 重载Python模块
            app.obj_ = py.importlib.import_module('CSP_SVM_test');
            py.importlib.reload(app.obj_);
            app.chan_num = 7;
            app.move_data = eye(app.chan_num);
            
            app.Paradigm = testgrading(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 5000];
            app.UIAxes.YLim = [0 1000];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.XTick = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000];
            app.UIAxes.XTickLabel = {'0'; '500'; '1000'; '1500'; '2000'; '2500'; '3000'; '3500'; '4000'; '4500'; '5000'};
            app.UIAxes.YTick = [100 200 300 400 500 600 700 800 900];
            app.UIAxes.YTickLabel = {'Ch_{1} PO6'; 'Ch_{2} PO4'; 'Ch_{3} POz'; 'Ch_{4} PO3'; 'Ch_{5} PO5'; 'Ch_{6} O2'; 'Ch_{7} Oz'; 'Ch_{8} O1'; 'Trigger'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @btnConnectPushed, true);
            app.btnConnect = uibutton(app.UIFigure, 'push');
            app.btnConnect.ButtonPushedFcn = createCallbackFcn(app, @btnConnectPushed, true);
            app.btnConnect.Text = '连接';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '幅值设置';
            app.popAmp = uidropdown(app.UIFigure);
            app.popAmp.Items = {'0.5μV', '1μV', '2μV', '5μV', '10μV', '20μV', '50μV', '100μV', '200μV', '500μV', '1000μV', '2000μV', '5000μV', '10000μV', '20000μV', '50000μV', '100000μV', '200000μV'};
            app.popAmp.ValueChangedFcn = createCallbackFcn(app, @popAmpValueChanged, true);
            app.popAmp.Value = '500μV';
            app.btnRecord = uibutton(app.UIFigure, 'push');
            app.btnRecord.ButtonPushedFcn = createCallbackFcn(app, @btnRecordPushed, true);
            app.btnRecord.Text = '开始记录';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '电量';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.Button_rand = uibutton(app.UIFigure, 'push');
            app.Button_rand.ButtonPushedFcn = createCallbackFcn(app, @Button_randPushed, true);
            app.Button_rand.Text = '无序采集';
            app.Button_sim = uibutton(app.UIFigure, 'push');
            app.Button_sim.ButtonPushedFcn = createCallbackFcn(app, @Button_simPushed, true);
            app.Button_sim.Text = '模拟采集';
            app.Button_train = uibutton(app.UIFigure, 'push');
            app.Button_train.ButtonPushedFcn = createCallbackFcn(app, @Button_train_Pushed, true);
            app.Button_train.Text = '模型训练';
            app.Button_localtest = uibutton(app.UIFigure, 'push');
            app.Button_localtest.ButtonPushedFcn = createCallbackFcn(app, @Button_localtest_Pushed, true);
            app.Button_localtest.Text = '本地测试';
            app.Button_onlinetest = uibutton(app.UIFigure, 'push');
            app.Button_onlinetest.ButtonPushedFcn = createCallbackFcn(app, @Button_onlinetest_Pushed, true);
            app.Button_onlinetest.Text = '在线测试';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end