classdef testgrading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
        btnFlag = 0; % 是否按下按钮
        labels = [];
        preds = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % 窗口最大化
%             drawnow
%             app.UIFigure.WindowState = 'maximized';
            app.CallingApp = mainapp;
            % 主界面按钮不可按
            app.CallingApp.Button_onlinetest.Enable='off';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.btnFlag = not(app.btnFlag);
            if app.btnFlag==1
                % 按下按钮主界面recordFlag为1，同时按钮变为不可按
%                 Record(app.CallingApp)
                app.Button.Enable = 'off';
                % 初始化空白界面
                axis(app.UIAxes, 'image');im = imread('.\pictures\blank.jpg');
                imagesc(app.UIAxes,im);pause(1);
                % 实验开始
                axis(app.UIAxes, 'image');im = imread('.\pictures\start.jpg');
                imagesc(app.UIAxes,im);pause(3);
                % 无序测试
                runs = 2;  % 采runs轮数据
                for run = 1:runs
                    % 创建随机数组
                    class_num = 2; % 2个类
                    trial_cla = 8; % 一轮每类x个trials
                    trial_num = class_num*trial_cla; % 一轮x个trials
                    seqCom = ones(trial_num, 1);
                    for i = 1:class_num-1
                        seqCom(trial_cla*i+1:trial_cla*(i+1)) = i+1;
                    end
                    rand('seed', run);
                    seqComplete = seqCom(randperm(trial_num));
                    disp(seqComplete')
                    % 准备开始运动想象
                    axis(app.UIAxes, 'image');im = imread('.\pictures\start_MI.jpg');
                    imagesc(app.UIAxes,im);pause(3);
                    % 请按照提示执行运动
                    for i = 1:trial_num
                        axis(app.UIAxes, 'image');im = imread('.\pictures\ready.jpg');
                        imagesc(app.UIAxes,im);pause(2);
                        axis(app.UIAxes, 'image');im = imread(['.\pictures\go_' num2str(seqComplete(i)) '.jpg']);
                        imagesc(app.UIAxes,im);
                        GetTrigger(app.CallingApp, seqComplete(i)); % 发送标签1-2
                        app.CallingApp.test_flag = 1;
                        pause(4);
                        % 输出分类结果并记录分数
                        app.labels = [app.labels seqComplete(i)];
                        app.preds = [app.preds app.CallingApp.y_pred_final];
                        if app.CallingApp.y_pred == seqComplete(i)
                            axis(app.UIAxes, 'image');im = imread('.\pictures\correct.jpg');imagesc(app.UIAxes,im);
                        else
                            axis(app.UIAxes, 'image');im = imread('.\pictures\wrong.jpg');imagesc(app.UIAxes,im);
                        end
                        app.CallingApp.test_flag = 0;
                        GetTrigger(app.CallingApp, 0); % 发送标签0恢复默认
                        pause(3);
%                         axis(app.UIAxes, 'image');im = imread('.\pictures\rest.jpg');
%                         imagesc(app.UIAxes,im);pause(3+rand(1));
                    end
                    if run < runs
                        axis(app.UIAxes, 'image');im = imread('.\pictures\break_30s.jpg');
                        imagesc(app.UIAxes,im);pause(20);
                        axis(app.UIAxes, 'image');im = imread('.\pictures\break_last_10s.jpg');
                        imagesc(app.UIAxes,im);pause(10);
                    end
                end
                % 实验结束
                axis(app.UIAxes, 'image');im = imread('.\pictures\over.jpg');
                imagesc(app.UIAxes,im);
                % 实验结束后recordFlag置为0，同时按钮文字变为返回主界面且按钮可按
%                 Record(app.CallingApp)
                app.Button.Text = '返回主界面';
                app.Button.Enable = 'on';
                plotconfusion(categorical(app.labels'),categorical(app.preds'))
            else
                % 按下结束按钮主界面按钮恢复可按，关闭当前界面，返回主界面，
                app.CallingApp.Button_onlinetest.Enable='on';
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 主界面按钮恢复可按再关闭app
            app.CallingApp.Button_onlinetest.Enable='on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '测试打分';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testgrading(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end